classdef Prototype < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Switch                matlab.ui.control.ToggleSwitch
        Switch_2              matlab.ui.control.ToggleSwitch
        Switch_3              matlab.ui.control.ToggleSwitch
        Switch_4              matlab.ui.control.ToggleSwitch
        Switch_5              matlab.ui.control.ToggleSwitch
        Switch_6              matlab.ui.control.ToggleSwitch
        Switch_7              matlab.ui.control.ToggleSwitch
        Switch_8              matlab.ui.control.ToggleSwitch
        Switch_9              matlab.ui.control.ToggleSwitch
        Switch_10             matlab.ui.control.ToggleSwitch
        Switch_11             matlab.ui.control.ToggleSwitch
        Switch_12             matlab.ui.control.ToggleSwitch
        Switch_13             matlab.ui.control.ToggleSwitch
        Switch_14             matlab.ui.control.ToggleSwitch
        Switch_15             matlab.ui.control.ToggleSwitch
        Switch_16             matlab.ui.control.ToggleSwitch
        UIAxes                matlab.ui.control.UIAxes
        CircuitBreakersLabel  matlab.ui.control.Label
        YouWinLabel           matlab.ui.control.Label
        YouWinLabel_2         matlab.ui.control.Label
    end

    
    properties (Access = private)
        tree = {['_a__b__and';'_a_nb__or_';'na__b_nor_';'_a_nb__and';'_a__b_nor_';'_a__b_nand';'na__b_nand';'_a__b__or_'];
                ['na__b_nor_';'_a__b_nand';'_a_nb_nand';'_a__b__or_'];
                ['_a_nb__and';'_a__b_nor_'];
                ['_a__b__and']}
%         tree = {['hi']}
%         inputs
        axis_lim = [0,3,0,3]
        scale = 20
        or_r_scale = 2.5;
        not_r_scale = 7;
        not_distance = 3;
        line_position = 0.5;
        line_width = 2;
        end_scale = 21;
        end_position = 0.25;
        l
        color
    end
    
    methods (Access = private)
        
        function update(app)
            for i = 1:length(app.tree)
                for j = 1:length(app.tree{i}(:,1))
                    a = app.color{i}(2*j-1,1);
                    b = app.color{i}(2*j,1);
                    
                    if strcmp('n',app.tree{i}(j,1))
                        a = not(a);
                    end
                    if strcmp('n',app.tree{i}(j,4))
                        b = not(b);
                    end

                    if strcmp('and',app.tree{i}(j,8:10))
                        c = and(a,b);
                    elseif strcmp('or',app.tree{i}(j,8:9))
                        c = or(a,b);
                    end
                    
                    if strcmp('n',app.tree{i}(j,7))
                        c = not(c);
                    end
                    
                    app.color{i+1}(j,1) = double(c);
                    
                    if app.color{i}(2*j-1,1)
                        set(app.l{i}(2*j-1,1),'Color','k')
                        set(app.l{i}(2*j-1,2),'Color','y')
                        set(app.l{i}(2*j-1,3),'Color','k')
                    else
                        set(app.l{i}(2*j-1,1),'Color','y')
                        set(app.l{i}(2*j-1,2),'Color','k')
                        set(app.l{i}(2*j-1,3),'Color','y')
                    end
                    if app.color{i}(2*j,1)
                        set(app.l{i}(2*j,1),'Color','k')
                        set(app.l{i}(2*j,2),'Color','y')
                        set(app.l{i}(2*j,3),'Color','k')
                    else
                        set(app.l{i}(2*j,1),'Color','y')
                        set(app.l{i}(2*j,2),'Color','k')
                        set(app.l{i}(2*j,3),'Color','y')
                    end
                end
            end
            
            % Need to add split to end
            if app.color{end}
                set(app.l{end-1},'FaceColor','y')
                set(app.l{end},'Color','y')
                while all(app.l{end-1}.FaceColor==[1,1,0])
                    app.YouWinLabel.FontColor = 'g';
                    app.YouWinLabel_2.FontColor = 'k';
                    pause(0.5)
                    if ~app.color{end}
                        break
                    end
                    app.YouWinLabel.FontColor = 'k';
                    app.YouWinLabel_2.FontColor = 'g';
                    pause(0.5)
                end
                app.YouWinLabel.FontColor = [0.8,0.8,0.8];
                app.YouWinLabel_2.FontColor = [0.8,0.8,0.8];
            else
                set(app.l{end-1},'FaceColor','k')
                set(app.l{end},'Color','k')
                app.YouWinLabel.FontColor = [0.8,0.8,0.8];
                app.YouWinLabel_2.FontColor = [0.8,0.8,0.8];
            end
        end
        
        function draw(app)
            cla(app.UIAxes)
            axis(app.UIAxes,app.axis_lim)
            hold(app.UIAxes,'on')
            
            inputs = 1:2*length(app.tree{1}(:,1));
            
            g = {};
            for i = 1:length(app.tree)
                for j = 1:length(app.tree{i}(:,1))
                    a = strcmp('n',app.tree{i}(j,1));
                    b = strcmp('n',app.tree{i}(j,4));
                    c = strcmp('n',app.tree{i}(j,7));
                    
                    if strcmp('and',app.tree{i}(j,8:10))
                        g{i}(j,1) = app.plot_and((inputs(2*j)+inputs(2*j-1))/2,i,a,b,c);
                    elseif strcmp('or',app.tree{i}(j,8:9))
                        g{i}(j,1) = app.plot_or((inputs(2*j)+inputs(2*j-1))/2,i,a,b,c);
                    end
                    inputs(j) = (inputs(2*j)+inputs(2*j-1))/2;
                end
            end
            clear inputs
            for i = 1:2*length(app.tree{1}(:,1))
                inputs{1}{i} = [i,0;
                                i,0];
            end
            
            for i = 1:length(app.tree)
                for j = 1:length(app.tree{i}(:,1))
                    a_line = [inputs{i}{2*j-1};g{i}(j).a];
                    b_line = [inputs{i}{2*j};g{i}(j).b];
                    app.l{i}(2*j-1,1) = plot(app.UIAxes,a_line(1:2,1),a_line(1:2,2),'k','LineWidth',app.line_width);
                    app.l{i}(2*j-1,2) = plot(app.UIAxes,a_line(2:3,1),a_line(2:3,2),'k','LineWidth',app.line_width);
                    app.l{i}(2*j-1,3) = plot(app.UIAxes,a_line(3:4,1),a_line(3:4,2),'k','LineWidth',app.line_width);
                    app.l{i}(2*j,1) = plot(app.UIAxes,b_line(1:2,1),b_line(1:2,2),'k','LineWidth',app.line_width);
                    app.l{i}(2*j,2) = plot(app.UIAxes,b_line(2:3,1),b_line(2:3,2),'k','LineWidth',app.line_width);
                    app.l{i}(2*j,3) = plot(app.UIAxes,b_line(3:4,1),b_line(3:4,2),'k','LineWidth',app.line_width);
                    app.color{i}(2*j-1,1) = 1;
                    app.color{i}(2*j,1) = 1;
                    inputs{i+1}{j} = g{i}(j).c;
                end
            end
            
            clear inputs
            inputs = 1:2*length(app.tree{1}(:,1));
            
            g = {};
            for i = 1:length(app.tree)
                for j = 1:length(app.tree{i}(:,1))
                    a = strcmp('n',app.tree{i}(j,1));
                    b = strcmp('n',app.tree{i}(j,4));
                    c = strcmp('n',app.tree{i}(j,7));
                    
                    if strcmp('and',app.tree{i}(j,8:10))
                        g{i}(j,1) = app.plot_and((inputs(2*j)+inputs(2*j-1))/2,i,a,b,c);
                    elseif strcmp('or',app.tree{i}(j,8:9))
                        g{i}(j,1) = app.plot_or((inputs(2*j)+inputs(2*j-1))/2,i,a,b,c);
                    end
                    inputs(j) = (inputs(2*j)+inputs(2*j-1))/2;
                end
            end
            
            circle = ([cos((0:0.01:2*pi)'),sin((0:0.01:2*pi)')]*app.end_scale).*[(app.axis_lim(2)-app.axis_lim(1))./100,(app.axis_lim(4)-app.axis_lim(3))./100]+[(app.axis_lim(2)-app.axis_lim(1))./2,+app.axis_lim(4)-app.end_position];
            app.l{end+1} = fill(app.UIAxes,circle(:,1),circle(:,2),'k');
            app.l{end}.EdgeColor = 'k';
            app.l{end+1} = plot(app.UIAxes,[g{end}(end).c(1,1),(app.axis_lim(2)-app.axis_lim(1))./2],[g{end}(end).c(1,2),min(circle(:,2))],'k','LineWidth',app.line_width);
            
            app.color{end} = 1;
            
        end
    
        function g = plot_and(app,x,y,nota,notb,notc)
            if ~exist('nota','var') || isempty(nota),nota = false;end
            if ~exist('notb','var') || isempty(notb),notb = false;end
            if ~exist('notc','var') || isempty(notc),notc = false;end
            and_points = [-1,1,-1,1]*app.scale;
            points = [and_points(1),and_points(3);
                      and_points(1),and_points(4);
                      (and_points(2)-and_points(1))/2*cos(flipud((0:0.01:pi)')),and_points(4)+(and_points(2)-and_points(1))/2*sin(flipud((0:0.01:pi)'));
                      and_points(2),and_points(4);
                      and_points(2),and_points(3)];
            fill(app.UIAxes,x+points(1:end,1).*(app.axis_lim(2)-app.axis_lim(1))./100,y+points(1:end,2).*(app.axis_lim(4)-app.axis_lim(3))./100,'g')
            
            g.a(1,1:2) = [-app.line_position*app.scale,and_points(3)].*[(app.axis_lim(2)-app.axis_lim(1))./100,(app.axis_lim(4)-app.axis_lim(3))./100]+[x,y];
            g.b(1,1:2) = [app.line_position*app.scale,and_points(3)].*[(app.axis_lim(2)-app.axis_lim(1))./100,(app.axis_lim(4)-app.axis_lim(3))./100]+[x,y];
            g.c(1,1:2) = [0,(and_points(2)-and_points(1))/2+(and_points(4)-and_points(3))/2+app.not_r_scale].*[(app.axis_lim(2)-app.axis_lim(1))./100,(app.axis_lim(4)-app.axis_lim(3))./100]+[x,y];
            
            g.a(2,1:2) = g.a(1,1:2);
            g.b(2,1:2) = g.b(1,1:2);
            g.c(2,1:2) = g.c(1,1:2);
            
            not_circle = [cos((0:0.01:2*pi)'),sin((0:0.01:2*pi)')]*app.not_r_scale;
            if nota
                position = [-app.line_position*app.scale,and_points(3)-app.not_r_scale - app.not_distance];
                fill(app.UIAxes,(not_circle(:,1)+position(1)).*(app.axis_lim(2)-app.axis_lim(1))./100+x,(not_circle(:,2)+position(2)).*(app.axis_lim(4)-app.axis_lim(3))./100+y,'r')
                g.a(1,1:2) = [-app.line_position*app.scale,and_points(3)-app.not_r_scale - app.not_distance].*[(app.axis_lim(2)-app.axis_lim(1))./100,(app.axis_lim(4)-app.axis_lim(3))./100]+[x,y];
            end
            if notb
                position = [app.line_position*app.scale,and_points(3)-app.not_r_scale - app.not_distance];
                fill(app.UIAxes,(not_circle(:,1)+position(1)).*(app.axis_lim(2)-app.axis_lim(1))./100+x,(not_circle(:,2)+position(2)).*(app.axis_lim(4)-app.axis_lim(3))./100+y,'r')
                g.b(1,1:2) = [app.line_position*app.scale,and_points(3)-app.not_r_scale - app.not_distance].*[(app.axis_lim(2)-app.axis_lim(1))./100,(app.axis_lim(4)-app.axis_lim(3))./100]+[x,y];
            end
            if notc
                position = [0,(and_points(2)-and_points(1))/2+(and_points(4)-and_points(3))/2+app.not_r_scale + app.not_distance];
                fill(app.UIAxes,(not_circle(:,1)+position(1)).*(app.axis_lim(2)-app.axis_lim(1))./100+x,(not_circle(:,2)+position(2)).*(app.axis_lim(4)-app.axis_lim(3))./100+y,'r')
                g.c(2,1:2) = [0,(and_points(2)-and_points(1))/2+(and_points(4)-and_points(3))/2+app.not_r_scale + app.not_distance].*[(app.axis_lim(2)-app.axis_lim(1))./100,(app.axis_lim(4)-app.axis_lim(3))./100]+[x,y];
            end
        end
        
        function g = plot_or(app,x,y,nota,notb,notc)
            if ~exist('nota','var') || isempty(nota),nota = false;end
            if ~exist('notb','var') || isempty(notb),notb = false;end
            if ~exist('notc','var') || isempty(notc),notc = false;end
            or_points = [-1,1,-1,1]*app.scale;
            circle = [cos(flipud((0:0.01:2*pi)')),sin(flipud((0:0.01:2*pi)'))]*(or_points(2)-or_points(1))*app.or_r_scale;
            line1 = circle(and(circle(:,1)<(min(circle(:,1))+(or_points(2)-or_points(1))/2),circle(:,2)>0),:);
            line1 = line1+[-max(line1(:,1)),-min(line1(:,2))-(or_points(4)-or_points(3))/2];
            line2 = flipud(line1.*[-1,1]);
            line3 = flipud(circle(and(and((circle(:,1)>or_points(1)),(circle(:,1)<or_points(2))),circle(:,2)>0),:));
            line3 = line3+[0,-line3(1,2)-(or_points(4)-or_points(3))/2];
            points = [line1;line2;line3];
            fill(app.UIAxes,x+points(1:end,1).*(app.axis_lim(2)-app.axis_lim(1))./100,y+points(1:end,2).*(app.axis_lim(4)-app.axis_lim(3))./100,'b')
            
            g.a(1,1:2) = [-app.line_position*app.scale,or_points(3)].*[(app.axis_lim(2)-app.axis_lim(1))./100,(app.axis_lim(4)-app.axis_lim(3))./100]+[x,y];
            g.b(1,1:2) = [app.line_position*app.scale,or_points(3)].*[(app.axis_lim(2)-app.axis_lim(1))./100,(app.axis_lim(4)-app.axis_lim(3))./100]+[x,y];
            g.c(1,1:2) = [0,(or_points(2)-or_points(1))/2+(or_points(4)-or_points(3))/2].*[(app.axis_lim(2)-app.axis_lim(1))./100,(app.axis_lim(4)-app.axis_lim(3))./100]+[x,y];
            
            g.a(2,1:2) = g.a(1,1:2);
            g.b(2,1:2) = g.b(1,1:2);
            g.c(2,1:2) = g.c(1,1:2);
            
            not_circle = [cos((0:0.01:2*pi)'),sin((0:0.01:2*pi)')]*app.not_r_scale;
            if nota
                position = [-app.line_position*app.scale,or_points(3)-app.not_r_scale - app.not_distance];
                fill(app.UIAxes,(not_circle(:,1)+position(1)).*(app.axis_lim(2)-app.axis_lim(1))./100+x,(not_circle(:,2)+position(2)).*(app.axis_lim(4)-app.axis_lim(3))./100+y,'r')
                g.a(1,1:2) = [-app.line_position*app.scale,or_points(3)-app.not_r_scale - app.not_distance].*[(app.axis_lim(2)-app.axis_lim(1))./100,(app.axis_lim(4)-app.axis_lim(3))./100]+[x,y];
            end
            if notb
                position = [app.line_position*app.scale,or_points(3)-app.not_r_scale - app.not_distance];
                fill(app.UIAxes,(not_circle(:,1)+position(1)).*(app.axis_lim(2)-app.axis_lim(1))./100+x,(not_circle(:,2)+position(2)).*(app.axis_lim(4)-app.axis_lim(3))./100+y,'r')
                g.b(1,1:2) = [app.line_position*app.scale,or_points(3)-app.not_r_scale - app.not_distance].*[(app.axis_lim(2)-app.axis_lim(1))./100,(app.axis_lim(4)-app.axis_lim(3))./100]+[x,y];
            end
            if notc
                position = [0,(or_points(2)-or_points(1))/2+(or_points(4)-or_points(3))/2+app.not_r_scale + app.not_distance];
                fill(app.UIAxes,(not_circle(:,1)+position(1)).*(app.axis_lim(2)-app.axis_lim(1))./100+x,(not_circle(:,2)+position(2)).*(app.axis_lim(4)-app.axis_lim(3))./100+y,'r')
                g.c(2,1:2) = [0,(or_points(2)-or_points(1))/2+(or_points(4)-or_points(3))/2+app.not_r_scale + app.not_distance].*[(app.axis_lim(2)-app.axis_lim(1))./100,(app.axis_lim(4)-app.axis_lim(3))./100]+[x,y];
            end
        end
    
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            app.axis_lim = [0,2*length(app.tree{1}(:,1))+1,0,length(app.tree)+1];
            app.scale = app.scale/max([length(app.tree{1}(:,1)),length(app.tree)]);
            app.not_r_scale = app.not_r_scale/max([length(app.tree{1}(:,1)),length(app.tree)]);
            app.end_scale = app.end_scale/max([length(app.tree{1}(:,1)),length(app.tree)]);
            app.draw
            app.color{1}(1,1) = str2num(app.Switch.Value);
            app.color{1}(2,1) = str2num(app.Switch_2.Value);
            app.color{1}(3,1) = str2num(app.Switch_3.Value);
            app.color{1}(4,1) = str2num(app.Switch_4.Value);
            app.color{1}(5,1) = str2num(app.Switch_5.Value);
            app.color{1}(6,1) = str2num(app.Switch_6.Value);
            app.color{1}(7,1) = str2num(app.Switch_7.Value);
            app.color{1}(8,1) = str2num(app.Switch_8.Value);
            app.color{1}(9,1) = str2num(app.Switch_9.Value);
            app.color{1}(10,1) = str2num(app.Switch_10.Value);
            app.color{1}(11,1) = str2num(app.Switch_11.Value);
            app.color{1}(12,1) = str2num(app.Switch_12.Value);
            app.color{1}(13,1) = str2num(app.Switch_13.Value);
            app.color{1}(14,1) = str2num(app.Switch_14.Value);
            app.color{1}(15,1) = str2num(app.Switch_15.Value);
            app.color{1}(16,1) = str2num(app.Switch_16.Value);
            app.update
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            app.color{1}(1,1) = str2num(app.Switch.Value);
            app.update
        end

        % Value changed function: Switch_2
        function Switch_2ValueChanged(app, event)
            app.color{1}(2,1) = str2num(app.Switch_2.Value);
            app.update
        end

        % Value changed function: Switch_3
        function Switch_3ValueChanged(app, event)
            app.color{1}(3,1) = str2num(app.Switch_3.Value);
            app.update
        end

        % Value changed function: Switch_4
        function Switch_4ValueChanged(app, event)
            app.color{1}(4,1) = str2num(app.Switch_4.Value);
            app.update
        end

        % Value changed function: Switch_5
        function Switch_5ValueChanged(app, event)
            app.color{1}(5,1) = str2num(app.Switch_5.Value);
            app.update
        end

        % Value changed function: Switch_6
        function Switch_6ValueChanged(app, event)
            app.color{1}(6,1) = str2num(app.Switch_6.Value);
            app.update
        end

        % Value changed function: Switch_7
        function Switch_7ValueChanged(app, event)
            app.color{1}(7,1) = str2num(app.Switch_7.Value);
            app.update
        end

        % Value changed function: Switch_8
        function Switch_8ValueChanged(app, event)
            app.color{1}(8,1) = str2num(app.Switch_8.Value);
            app.update
        end

        % Value changed function: Switch_9
        function Switch_9ValueChanged(app, event)
            app.color{1}(9,1) = str2num(app.Switch_9.Value);
            app.update
        end

        % Value changed function: Switch_10
        function Switch_10ValueChanged(app, event)
            app.color{1}(10,1) = str2num(app.Switch_10.Value);
            app.update
        end

        % Value changed function: Switch_11
        function Switch_11ValueChanged(app, event)
            app.color{1}(11,1) = str2num(app.Switch_11.Value);
            app.update
        end

        % Value changed function: Switch_12
        function Switch_12ValueChanged(app, event)
            app.color{1}(12,1) = str2num(app.Switch_12.Value);
            app.update
        end

        % Value changed function: Switch_13
        function Switch_13ValueChanged(app, event)
            app.color{1}(13,1) = str2num(app.Switch_13.Value);
            app.update
        end

        % Value changed function: Switch_14
        function Switch_14ValueChanged(app, event)
            app.color{1}(14,1) = str2num(app.Switch_14.Value);
            app.update
        end

        % Value changed function: Switch_15
        function Switch_15ValueChanged(app, event)
            app.color{1}(15,1) = str2num(app.Switch_15.Value);
            app.update
        end

        % Value changed function: Switch_16
        function Switch_16ValueChanged(app, event)
            app.color{1}(16,1) = str2num(app.Switch_16.Value);
            app.update
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Switch = uiswitch(app.UIFigure, 'toggle');
            app.Switch.ItemsData = {'0', '1'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = '0';
            app.Switch_2 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_2.ItemsData = {'0', '1'};
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @Switch_2ValueChanged, true);
            app.Switch_2.Value = '0';
            app.Switch_3 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_3.ItemsData = {'0', '1'};
            app.Switch_3.ValueChangedFcn = createCallbackFcn(app, @Switch_3ValueChanged, true);
            app.Switch_3.Value = '0';
            app.Switch_4 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_4.ItemsData = {'0', '1'};
            app.Switch_4.ValueChangedFcn = createCallbackFcn(app, @Switch_4ValueChanged, true);
            app.Switch_4.Value = '0';
            app.Switch_5 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_5.ItemsData = {'0', '1'};
            app.Switch_5.ValueChangedFcn = createCallbackFcn(app, @Switch_5ValueChanged, true);
            app.Switch_5.Value = '0';
            app.Switch_6 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_6.ItemsData = {'0', '1'};
            app.Switch_6.ValueChangedFcn = createCallbackFcn(app, @Switch_6ValueChanged, true);
            app.Switch_6.Value = '0';
            app.Switch_7 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_7.ItemsData = {'0', '1'};
            app.Switch_7.ValueChangedFcn = createCallbackFcn(app, @Switch_7ValueChanged, true);
            app.Switch_7.Value = '0';
            app.Switch_8 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_8.ItemsData = {'0', '1'};
            app.Switch_8.ValueChangedFcn = createCallbackFcn(app, @Switch_8ValueChanged, true);
            app.Switch_8.Value = '0';
            app.Switch_9 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_9.ItemsData = {'0', '1'};
            app.Switch_9.ValueChangedFcn = createCallbackFcn(app, @Switch_9ValueChanged, true);
            app.Switch_9.Value = '0';
            app.Switch_10 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_10.ItemsData = {'0', '1'};
            app.Switch_10.ValueChangedFcn = createCallbackFcn(app, @Switch_10ValueChanged, true);
            app.Switch_10.Value = '0';
            app.Switch_11 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_11.ItemsData = {'0', '1'};
            app.Switch_11.ValueChangedFcn = createCallbackFcn(app, @Switch_11ValueChanged, true);
            app.Switch_11.Value = '0';
            app.Switch_12 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_12.ItemsData = {'0', '1'};
            app.Switch_12.ValueChangedFcn = createCallbackFcn(app, @Switch_12ValueChanged, true);
            app.Switch_12.Value = '0';
            app.Switch_13 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_13.ItemsData = {'0', '1'};
            app.Switch_13.ValueChangedFcn = createCallbackFcn(app, @Switch_13ValueChanged, true);
            app.Switch_13.Value = '0';
            app.Switch_14 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_14.ItemsData = {'0', '1'};
            app.Switch_14.ValueChangedFcn = createCallbackFcn(app, @Switch_14ValueChanged, true);
            app.Switch_14.Value = '0';
            app.Switch_15 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_15.ItemsData = {'0', '1'};
            app.Switch_15.ValueChangedFcn = createCallbackFcn(app, @Switch_15ValueChanged, true);
            app.Switch_15.Value = '0';
            app.Switch_16 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_16.ItemsData = {'0', '1'};
            app.Switch_16.ValueChangedFcn = createCallbackFcn(app, @Switch_16ValueChanged, true);
            app.Switch_16.Value = '0';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            app.CircuitBreakersLabel = uilabel(app.UIFigure);
            app.CircuitBreakersLabel.Text = 'Circuit Breakers';
            app.YouWinLabel = uilabel(app.UIFigure);
            app.YouWinLabel.Text = 'You Win!';
            app.YouWinLabel_2 = uilabel(app.UIFigure);
            app.YouWinLabel_2.Text = 'You Win!';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Prototype

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end