classdef prototype < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        UIAxes              matlab.ui.control.UIAxes
        XSlider             matlab.ui.control.Slider
        YSlider_2           matlab.ui.control.Slider
        UIAxes2             matlab.ui.control.UIAxes
        Knob                matlab.ui.control.Knob
        Label               matlab.ui.control.Label
        EnterCodeEditField  matlab.ui.control.NumericEditField
        CodeIncorrectLabel  matlab.ui.control.Label
        EnterCodeButton     matlab.ui.control.Button
        UIAxes3             matlab.ui.control.UIAxes
        Label_2             matlab.ui.control.Label
        Label_3             matlab.ui.control.Label
        Label_4             matlab.ui.control.Label
        Label_5             matlab.ui.control.Label
    end

    
    properties (Access = private)
        X
        Y
        theta
        
        laser_list = [];
                  
        code
        
        one = ['. ';
               '  ';
               '  '];
        two = ['. ';
               '. ';
               '  '];   
        three = ['..';
                 '  ';
                 '  '];
        four = ['..';
                ' .';
                '  '];
        five = ['. ';
                ' .';
                '  '];
        six = ['..';
               '. ';
               '  '];   
        seven = ['..';
                 '..';
                 '  '];
        eight = ['. ';
                 '..';
                 '  '];
        nine = [' .';
                '. ';
                '  '];
        zero = [' .';
                '..';
                '  ']; 
            
        alphabet = 'ABCDEFGHIJKLMNOPQRSTUVWXYZ';
        directions = ['0°  ';'45° ';'90° ';'135°';'180°';'225°';'270°';'315°']
        direction_numbers = [0,pi/4,pi/2,3*pi/4,pi,-3*pi/4,-pi/2,-pi/4];
           
        board_length = 1;
        laser_range = pi/2;
        
        line_handle
        laser_handle
        dot_handle
    end
    
    methods (Access = private)
        
        function set_up(app)
            whole_text = [];
            for i = 1:length(app.code)
                solution_position = [randi([2,9]),randi([2,9]),randi([1,length(app.directions(:,1))])];
                
                switch app.code(i)
                    case 1
                        brail = app.one;
                    case 2
                        brail = app.two;
                    case 3
                        brail = app.three;
                    case 4
                        brail = app.four;
                    case 5
                        brail = app.five;
                    case 6
                        brail = app.six;
                    case 7
                        brail = app.seven;
                    case 8
                        brail = app.eight;
                    case 9
                        brail = app.nine;
                    case 0
                        brail = app.zero;
                end
                
%                 solution_position = [5.5,5.5,8];
                
                for j = 1:3
                    for k = 1:2
                        if strcmp(brail(j,k),'.')
                            direction = rand*app.laser_range+app.direction_numbers(solution_position(3))-app.laser_range/2;
                            
                            m_laser = tan(-direction+pi/2);
                            board_unit_vec = [sin(app.direction_numbers(solution_position(1,3))+pi/2),cos(app.direction_numbers(solution_position(1,3))+pi/2)];
                            if k == 1
                                point = solution_position(1:2)+board_unit_vec*app.board_length/4;
                            else
                                point = solution_position(1:2)-board_unit_vec*app.board_length/4;
                            end
                            
                            b_laser = point(2)-m_laser*point(1);
                            
                            intercepts = Inf(4,2);
                            intercepts(1,:) = [1,m_laser+b_laser];
                            if approx(m_laser,0,2,10^-10)
                                intercepts(2,:) = [Inf,10];
                            elseif (m_laser>10^10)
                                intercepts(2,:) = [0,10];
                            else
                                intercepts(2,:) = [(10-b_laser)/m_laser,10];
                            end
                            intercepts(3,:) = [10,m_laser*10+b_laser];
                            if approx(m_laser,0,2,10^-10)
                                intercepts(4,:) = [Inf,1];
                            elseif (m_laser>10^10)
                                intercepts(4,:) = [0,1];
                            else
                                intercepts(4,:) = [(1-b_laser)/m_laser,1];
                            end
                            
                            wall_index = 0;
                            board_unit_vec = [sin(app.direction_numbers(solution_position(1,3))),cos(app.direction_numbers(solution_position(1,3)))];
                            for l = 1:4
                                if ((intercepts(l,1) >= 1)||approx(intercepts(l,1),1)) && ((intercepts(l,2) >= 1)||approx(intercepts(l,2),1)) && ((intercepts(l,1) <= 10)||approx(intercepts(l,1),10)) && ((intercepts(l,2) <= 10)||approx(intercepts(l,2),10))
                                    laser_vec = intercepts(l,:) - point;
                                    angle = acos(dot(laser_vec,board_unit_vec)/(norm(laser_vec)*norm(board_unit_vec)));
                                    if angle<(pi/2)
                                        wall_index = l;
                                    end
                                end
                            end
                            
                            app.laser_list(end+1,:) = [intercepts(wall_index,:),4-j,direction+pi];
                        end
                    end
                end
                if ~isempty(whole_text)
                    whole_text{i} = char(strcat(app.alphabet(solution_position(2)),string(solution_position(1))," - ",app.directions(solution_position(3),:)));
                else
                    whole_text{1} = char(strcat(app.alphabet(solution_position(2)),string(solution_position(1))," - ",app.directions(solution_position(3),:)));
                end
            end 
            app.Label.Text = whole_text;
            quiver(app.UIAxes3,[1,1,1,1],[1,1,1,1],[0,1,0,-1],[1,0,-1,0],'-k','LineWidth',2,'MaxHeadSize',10)
            plot(app.UIAxes2,[0.25,0.75,0.25,0.75,0.25,0.75],[1,1,2,2,3,3],'ok','MarkerSize',7);
        end
        
        function update_grid(app)
            
            line = [app.X,app.Y] + app.board_length/2*[sin(app.theta+pi/2),cos(app.theta+pi/2);
                                                     -sin(app.theta+pi/2),-cos(app.theta+pi/2)];
            arrow = [sin(app.theta),cos(app.theta)]/4;                                    
            
            if isempty(app.line_handle)
                app.line_handle(1) = plot(app.UIAxes,line(:,1),line(:,2),'-k','LineWidth',3);
                app.line_handle(2) = quiver(app.UIAxes,app.X,app.Y,arrow(1),arrow(2),'--k','LineWidth',0.5,'MaxHeadSize',1);
            else
                set(app.line_handle(1),'XData',line(:,1),'YData',line(:,2));
                set(app.line_handle(2),'XData',app.X,'YData',app.Y,'UData',arrow(1),'VData',arrow(2));
            end
        end
    
        function update_board(app)
            dot = ones(length(app.laser_list(:,1)),2)*100;
            for i = 1:length(app.laser_list(:,1))
                laser_line = app.laser_list(i,1:2) + [0,0;
                                                      100*cos(-app.laser_list(i,4)+pi/2),100*sin(-app.laser_list(i,4)+pi/2)];
                board_line = [app.X,app.Y] + app.board_length/2*[sin(app.theta+pi/2),cos(app.theta+pi/2);
                                                     -sin(app.theta+pi/2),-cos(app.theta+pi/2)];
                                                      
                m_laser = tan(-app.laser_list(i,4)+pi/2);
                m_board = tan(-app.theta);
                
                b_laser = app.laser_list(i,2)-m_laser*app.laser_list(i,1);
                b_board = app.Y-m_board*app.X;
                
                m_oboard = tan(-app.theta+pi/2);
                [~,index] = min([norm(app.laser_list(i,1:2)-board_line(1,:)),norm(app.laser_list(i,1:2)-board_line(2,:))]);
                b_oboard = board_line(index,2)-m_oboard*board_line(index,1);
                
                if ~approx(m_laser,m_board,2,0.01)
                    x = (b_board-b_laser)/(m_laser-m_board);
                    
                    if ((min(board_line(:,1))<=x) && (x<=max(board_line(:,1))))
                        if (m_laser>10^10) || (m_laser<-10^10)
                            laser_line(2,:) = [x,m_board*x+b_board];
                        else
                            laser_line(2,:) = [x,m_laser*x+b_laser];
                        end
                        
                        laser_vec = [cos(-app.laser_list(i,4)+pi/2);sin(-app.laser_list(i,4)+pi/2);0];
                        board_vec = [cos(-app.theta);sin(-app.theta);0];
                        
                        side_vec = cross(board_vec,laser_vec);
                        
                        if 0>side_vec(3)
                            left_point = board_line(1,:).';
                            intersection_point = laser_line(2,:).';
                            postion = norm(intersection_point-left_point);
                            dot(i,:) = [postion,app.laser_list(i,3)];
                        end
                    end
                else
                    x = (b_oboard-b_laser)/(m_laser-m_oboard);
                    
                    if approx(b_board,b_laser,1,0.01)
                        laser_line(2,:) = [x,m_laser*x+b_laser];
                    end
                end
                
                             
                if length(app.laser_handle)<i
                    app.laser_handle(i) = plot(app.UIAxes,laser_line(:,1),laser_line(:,2),'Color',[0.224,1,0.078],'LineWidth',1);
                else
                    set(app.laser_handle(i),'XData',laser_line(:,1),'YData',laser_line(:,2),'Color',[0.224,1,0.078],'LineWidth',1);
                end
                if length(app.dot_handle)<i
                    app.dot_handle(i) = plot(app.UIAxes2,dot(i,1),dot(i,2),'.','Color',[0.224,1,0.078],'MarkerSize',20);
                else
                    set(app.dot_handle(i),'XData',dot(i,1),'YData',dot(i,2));
                end
            end
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%             rng('default')
            
            app.code = [randi([0,9]),randi([0,9]),randi([0,9]),randi([0,9])];
            
            hold(app.UIAxes,'on')
            hold(app.UIAxes2,'on')
            
            app.set_up;
            app.X = app.XSlider.Value;
            app.Y = app.YSlider_2.Value;
            app.theta = app.Knob.Value;
            app.update_grid
            app.update_board
        end

        % Value changing function: XSlider
        function XSliderValueChanging(app, event)
            app.X = event.Value;
            app.update_board
            app.update_grid;
        end

        % Value changing function: YSlider_2
        function YSlider_2ValueChanging(app, event)
            app.Y = event.Value;
            app.update_board
            app.update_grid;
        end

        % Value changing function: Knob
        function KnobValueChanging(app, event)
            app.theta = event.Value;
            app.update_board
            app.update_grid
        end

        % Button pushed function: EnterCodeButton
        function EnterCodeButtonPushed(app, event)
            end_code = 0;
            sep_code = fliplr(app.code);
            for i = fliplr(1:length(app.code))
                end_code = end_code+sep_code(i)*10^(i-1);
            end
            if app.EnterCodeEditField.Value == end_code
                app.CodeIncorrectLabel.Text = "CORRECT";
                app.CodeIncorrectLabel.FontColor = 'g';
            else
                app.CodeIncorrectLabel.Text = "Code Incorrect";
                app.CodeIncorrectLabel.FontColor = 'k';
                pause(0.5)
                app.CodeIncorrectLabel.FontColor = [0.94,0.94,0.94];
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XLim = [1 10];
            app.UIAxes.YLim = [1 10];
            app.UIAxes.YTickLabel = {'A'; 'B'; 'C'; 'D'; 'E'; 'F'; 'G'; 'H'; 'I'; 'J'};
            app.XSlider = uislider(app.UIFigure);
            app.XSlider.Limits = [1 10];
            app.XSlider.MajorTicks = [];
            app.XSlider.ValueChangingFcn = createCallbackFcn(app, @XSliderValueChanging, true);
            app.XSlider.MinorTicks = [];
            app.XSlider.Value = 5.5;
            app.YSlider_2 = uislider(app.UIFigure);
            app.YSlider_2.Limits = [1 10];
            app.YSlider_2.MajorTicks = [];
            app.YSlider_2.MajorTickLabels = {'A', 'B', 'C', 'D', 'E', 'F', 'G', 'H', 'I', 'J'};
            app.YSlider_2.ValueChangingFcn = createCallbackFcn(app, @YSlider_2ValueChanging, true);
            app.YSlider_2.MinorTicks = [];
            app.YSlider_2.Value = 5.5;
            app.UIAxes2 = uiaxes(app.UIFigure);
            app.UIAxes2.XLim = [0 1];
            app.UIAxes2.YLim = [0 4];
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.Knob = uiknob(app.UIFigure, 'continuous');
            app.Knob.Limits = [-3.14159265358979 3.14159265358979];
            app.Knob.MajorTicks = [-3.14159265358979 0 3.14159265358979];
            app.Knob.MajorTickLabels = {'', ''};
            app.Knob.ValueChangingFcn = createCallbackFcn(app, @KnobValueChanging, true);
            app.Knob.MinorTicks = [];
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.EnterCodeEditField = uieditfield(app.UIFigure, 'numeric');
            app.CodeIncorrectLabel = uilabel(app.UIFigure);
            app.CodeIncorrectLabel.Text = 'Code Incorrect';
            app.EnterCodeButton = uibutton(app.UIFigure, 'push');
            app.EnterCodeButton.ButtonPushedFcn = createCallbackFcn(app, @EnterCodeButtonPushed, true);
            app.EnterCodeButton.Text = 'Enter Code';
            app.UIAxes3 = uiaxes(app.UIFigure);
            app.UIAxes3.XLim = [0 2];
            app.UIAxes3.YLim = [0 2];
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '0°';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '90°';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '180°';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '270°';
        end
    end

    methods (Access = public)

        % Construct app
        function app = prototype

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end