classdef prototype < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        Switch_2     matlab.ui.control.ToggleSwitch
        Switch_3     matlab.ui.control.ToggleSwitch
        Switch_4     matlab.ui.control.ToggleSwitch
        Switch_5     matlab.ui.control.ToggleSwitch
        UIAxes       matlab.ui.control.UIAxes
        Switch       matlab.ui.control.ToggleSwitch
        EmergencyPowerReroutingSwitchesLabel  matlab.ui.control.Label
        YOUWINLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        code = [3,2,5,1,4];
        input = [0,0,0,0,0];
        offset = 0.25;
        text_position = [3;-0.4]
        wires = [2;1;0;-1;-2]
        wire_length = 2
        colors = ['-r';'-g';'-y';'-w';'-b']
        rotation
    end
    
    methods (Access = private)
        
        function check_end(app)
            if isequal(app.input,app.code)
                app.YOUWINLabel.Visible = 'on';
            else
                app.YOUWINLabel.Visible = 'off';
            end
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.check_end;
            app.rotation = rand*2*pi-pi;
            
            cla(app.UIAxes)
            hold(app.UIAxes,'on')
            
            R = [cos(app.rotation),-sin(app.rotation);
                 sin(app.rotation),cos(app.rotation)];
             
            app.text_position = R*app.text_position;
            
            text(app.UIAxes,app.text_position(2),app.text_position(1),'Top','Rotation',-app.rotation*180/pi);
            
            for i = 1:length(app.wires)
                position = app.wires(i) + app.offset;
                points = R*[position,position;
                            app.wire_length/2,-app.wire_length/2];
                plot(app.UIAxes,points(2,:),points(1,:),app.colors(i,:),'LineWidth',1.5)
                
                position = app.wires(i) - app.offset;
                points = R*[position,position;
                            app.wire_length/2,-app.wire_length/2];
                plot(app.UIAxes,points(2,:),points(1,:),'-k','LineWidth',1.5)
            end
                     
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = str2double(app.Switch.Value);
            if value
                index = find(~app.input,1);
                if ~isempty(index)
                    app.input(index) = 1;
                end
            else
                if (~str2double(app.Switch.Value)) && (~str2double(app.Switch_2.Value)) && (~str2double(app.Switch_3.Value)) && (~str2double(app.Switch_4.Value)) && (~str2double(app.Switch_5.Value))
                    app.input = [0,0,0,0,0];
                end
            end
            app.check_end;
        end

        % Value changed function: Switch_2
        function Switch_2ValueChanged(app, event)
            value = str2double(app.Switch_2.Value);
            if value
                index = find(~app.input,1);
                if ~isempty(index)
                    app.input(index) = 2;
                end
            else
                if (~str2double(app.Switch.Value)) && (~str2double(app.Switch_2.Value)) && (~str2double(app.Switch_3.Value)) && (~str2double(app.Switch_4.Value)) && (~str2double(app.Switch_5.Value))
                    app.input = [0,0,0,0,0];
                end
            end
            app.check_end;
        end

        % Value changed function: Switch_3
        function Switch_3ValueChanged(app, event)
            value = str2double(app.Switch_3.Value);
            if value
                index = find(~app.input,1);
                if ~isempty(index)
                    app.input(index) = 3;
                end
            else
                if (~str2double(app.Switch.Value)) && (~str2double(app.Switch_2.Value)) && (~str2double(app.Switch_3.Value)) && (~str2double(app.Switch_4.Value)) && (~str2double(app.Switch_5.Value))
                    app.input = [0,0,0,0,0];
                end
            end
            app.check_end;
        end

        % Value changed function: Switch_4
        function Switch_4ValueChanged(app, event)
            value = str2double(app.Switch_4.Value);
            if value
                index = find(~app.input,1);
                if ~isempty(index)
                    app.input(index) = 4;
                end
            else
                if (~str2double(app.Switch.Value)) && (~str2double(app.Switch_2.Value)) && (~str2double(app.Switch_3.Value)) && (~str2double(app.Switch_4.Value)) && (~str2double(app.Switch_5.Value))
                    app.input = [0,0,0,0,0];
                end
            end
            app.check_end;
        end

        % Value changed function: Switch_5
        function Switch_5ValueChanged(app, event)
            value = str2double(app.Switch_5.Value);
            if value
                index = find(~app.input,1);
                if ~isempty(index)
                    app.input(index) = 5;
                end
            else
                if (~str2double(app.Switch.Value)) && (~str2double(app.Switch_2.Value)) && (~str2double(app.Switch_3.Value)) && (~str2double(app.Switch_4.Value)) && (~str2double(app.Switch_5.Value))
                    app.input = [0,0,0,0,0];
                end
            end
            app.check_end;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Switch = uiswitch(app.UIFigure, 'toggle');
            app.Switch.ItemsData = {'0', '1'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = '0';
            app.Switch_2 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_2.ItemsData = {'0', '1'};
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @Switch_2ValueChanged, true);
            app.Switch_2.Value = '0';
            app.Switch_3 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_3.ItemsData = {'0', '1'};
            app.Switch_3.ValueChangedFcn = createCallbackFcn(app, @Switch_3ValueChanged, true);
            app.Switch_3.Value = '0';
            app.Switch_4 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_4.ItemsData = {'0', '1'};
            app.Switch_4.ValueChangedFcn = createCallbackFcn(app, @Switch_4ValueChanged, true);
            app.Switch_4.Value = '0';
            app.Switch_5 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_5.ItemsData = {'0', '1'};
            app.Switch_5.ValueChangedFcn = createCallbackFcn(app, @Switch_5ValueChanged, true);
            app.Switch_5.Value = '0';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XLim = [-5 5];
            app.UIAxes.YLim = [-5 5];
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.EmergencyPowerReroutingSwitchesLabel = uilabel(app.UIFigure);
            app.EmergencyPowerReroutingSwitchesLabel.Text = 'Emergency Power Rerouting Switches';
            app.YOUWINLabel = uilabel(app.UIFigure);
            app.YOUWINLabel.Text = 'YOU WIN';
        end
    end

    methods (Access = public)

        % Construct app
        function app = prototype

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end