classdef prototype_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        UIAxes                   matlab.ui.control.UIAxes
        UIAxes_2                 matlab.ui.control.UIAxes
        WireStartEditFieldLabel  matlab.ui.control.Label
        WireStartEditField       matlab.ui.control.EditField
        WireEndEditFieldLabel    matlab.ui.control.Label
        WireEndEditField         matlab.ui.control.EditField
        AddWireButton            matlab.ui.control.Button
        RemoveWireButton         matlab.ui.control.Button
        YouWinLabel              matlab.ui.control.Label
        WiresListBoxLabel        matlab.ui.control.Label
        WiresListBox             matlab.ui.control.ListBox
    end

    
    properties (Access = private)
        wire_list = ['E1 D1 ';
                     'C1 B2 ';
                     'B1 A2 ';
                     'B3 C3 ';
                     'C4 D3 ';
                     'D4 C5 ';
                     'B4 A4 ';
                     'B5 A5 '];
        
        
        line_list
        maze_line_list
        choosen
        points
        
        dimensions = [5,5]
        
        begining = [6,0]
        start = [5,1]
        stop = [1,5]
        goal = [0,6]
        
        x_names = 1:27
        y_names = 'ABCDEFGHIJKLMNOPQRSTUVWXYZ'
        
        max_delta = 1
        
        click_count = 0
    end
    
    methods (Access = private)


        function draw(app)
            cla(app.UIAxes)
            cla(app.UIAxes_2)
            hold(app.UIAxes,'on')
            hold(app.UIAxes_2,'on')
            axis(app.UIAxes,[0,app.dimensions(2)+1,0,app.dimensions(1)+1])
            axis(app.UIAxes_2,[0,app.dimensions(2)+1,0,app.dimensions(1)+1])
            set(app.UIAxes,'YDir','reverse')
            set(app.UIAxes_2,'YDir','reverse')
            
            app.points = zeros(app.dimensions);
            
            options = []
            for i = 1:app.dimensions(1)
                for j = 1:app.dimensions(2)
                    options(end+1,1:2) = [i,j];
                end
            end

            for i = 1:app.dimensions(1)
                for j = 1:app.dimensions(2)
                    index = randi([1,length(options(:,1))]);
                    
                    x = options(index,1);
                    y = options(index,2);
                    
                    options(index,:) = [];
                    
                    app.points(i,j,1:2) = [x,y];
                end
            end
            
            for i = 1:app.dimensions(1)
                for j = 1:app.dimensions(2)
                    if i>1,plot(app.UIAxes,[app.points(i-1,j,1),app.points(i,j,1)],[app.points(i-1,j,2),app.points(i,j,2)],'-k'),end
                    if j>1,plot(app.UIAxes,[app.points(i,j-1,1),app.points(i,j,1)],[app.points(i,j-1,2),app.points(i,j,2)],'-k'),end
                    if i>1 && j>1,plot(app.UIAxes,[app.points(i-1,j-1,1),app.points(i,j,1)],[app.points(i-1,j-1,2),app.points(i,j,2)],'-k'),end
                    if i<app.dimensions(1),plot(app.UIAxes,[app.points(i+1,j,1),app.points(i,j,1)],[app.points(i+1,j,2),app.points(i,j,2)],'-k'),end
                    if j<app.dimensions(2),plot(app.UIAxes,[app.points(i,j+1,1),app.points(i,j,1)],[app.points(i,j+1,2),app.points(i,j,2)],'-k'),end
                    if i<app.dimensions(1) && j<app.dimensions(2),plot(app.UIAxes,[app.points(i+1,j+1,1),app.points(i,j,1)],[app.points(i+1,j+1,2),app.points(i,j,2)],'-k'),end
                    if i>1 && j<app.dimensions(2),plot(app.UIAxes,[app.points(i-1,j+1,1),app.points(i,j,1)],[app.points(i-1,j+1,2),app.points(i,j,2)],'-k'),end
                    if j>1 && i<app.dimensions(1),plot(app.UIAxes,[app.points(i+1,j-1,1),app.points(i,j,1)],[app.points(i+1,j-1,2),app.points(i,j,2)],'-k'),end
                end
            end
            
            for i = 1:app.dimensions(1)
                for j = 1:app.dimensions(2)
                    text(app.UIAxes,app.points(i,j,1),app.points(i,j,2),app.y_names(i)+string(app.x_names(j)))
                end
            end
            y = [0,find(app.y_names==app.wire_list(1,1))];
            x = [0,str2double(app.wire_list(1,2:3))];
            plot(app.UIAxes,[app.begining(2),app.points(y(2),x(2),1)],[app.begining(1),app.points(y(2),x(2),2)],'b','LineWidth',2);

            for i = 1:length(app.wire_list)
                y = [find(app.y_names==app.wire_list(i,1)),find(app.y_names==app.wire_list(i,4))];
                x = [str2double(app.wire_list(i,2:3)),str2double(app.wire_list(i,5:6))];
                app.maze_line_list(end+1,1) = plot(app.UIAxes,[app.points(y(1),x(1),1),app.points(y(2),x(2),1)],[app.points(y(1),x(1),2),app.points(y(2),x(2),2)],'k','LineWidth',0.5);
                
                if i == length(app.wire_list)
                    y = [find(app.y_names==app.wire_list(i,4)),0];
                    x = [str2double(app.wire_list(i,5:6)),0];
                    app.maze_line_list(end,2) = plot(app.UIAxes,[app.points(y(1),x(1),1),app.goal(2)],[app.points(y(1),x(1),2),app.goal(1)],'k','LineWidth',0.5);
                else
                    y = [find(app.y_names==app.wire_list(i,4)),find(app.y_names==app.wire_list(i+1,1))];
                    x = [str2double(app.wire_list(i,5:6)),str2double(app.wire_list(i+1,2:3))];
                    app.maze_line_list(end,2) = plot(app.UIAxes,[app.points(y(1),x(1),1),app.points(y(2),x(2),1)],[app.points(y(1),x(1),2),app.points(y(2),x(2),2)],'k','LineWidth',0.5);
                end
            end
            
            app.choosen = zeros(size(app.wire_list));
            
            for i = 1:app.dimensions(1)
                for j = 1:app.dimensions(2)
                    text(app.UIAxes_2,j,i,app.y_names(i)+string(app.x_names(j)))
                end
            end
        end
        
        function check_end(app)
            
        end
    
        function update_maze(app)
            
            if ~isempty(app.WiresListBox.Items)
                connected = ismember(app.wire_list,cell2mat(app.WiresListBox.Items.'),'rows');
            else 
                connected = zeros(length(app.wire_list),1);
            end
            
            until = find(not(connected),1);
            
            if isempty(until)
                until = length(app.WiresListBox.Items)+1;
                app.YouWinLabel.FontColor = 'g';
            else
                app.YouWinLabel.FontColor = [0.94,0.94,0.94];
            end
            
            for i = 1:until-1
                set(app.maze_line_list(i,1),'Color','b','LineWidth',2)
                set(app.maze_line_list(i,2),'Color','b','LineWidth',2)
            end
            for i = until:length(app.maze_line_list)
                set(app.maze_line_list(i,1),'Color','k','LineWidth',0.5)
                set(app.maze_line_list(i,2),'Color','k','LineWidth',0.5)
            end
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.draw;
        end

        % Button pushed function: AddWireButton
        function AddWireButtonPushed(app, event)
            wire_start = app.WireStartEditField.Value;
            wire_stop = app.WireEndEditField.Value;
            
            y = [find(app.y_names==wire_start(1)),find(app.y_names==wire_stop(1))];
            x = [str2double(wire_start(2:end)),str2double(wire_stop(2:end))];
            
            if length(wire_start) < 3
                wire_start = [wire_start,' '];
            end
            if length(wire_stop) < 3
                wire_stop = [wire_stop,' '];
            end
            
            app.WiresListBox.Items{end+1} = [wire_start,wire_stop];
            
            app.line_list(end+1,1) = plot(app.UIAxes_2,x,y,'b','LineWidth',1.5);
            app.update_maze;
            app.check_end;
        end

        % Button pushed function: RemoveWireButton
        function RemoveWireButtonPushed(app, event)
            delete(app.line_list(strcmp(app.WiresListBox.Items,app.WiresListBox.Value),:));
            app.line_list(strcmp(app.WiresListBox.Items,app.WiresListBox.Value),:) = [];
            app.WiresListBox.Items(strcmp(app.WiresListBox.Items,app.WiresListBox.Value)) = [];
            app.update_maze;
            app.check_end;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes_2 = uiaxes(app.UIFigure);
            app.WireStartEditFieldLabel = uilabel(app.UIFigure);
            app.WireStartEditFieldLabel.Text = 'Wire Start';
            app.WireStartEditField = uieditfield(app.UIFigure, 'text');
            app.WireStartEditField.Value = 'E1';
            app.WireEndEditFieldLabel = uilabel(app.UIFigure);
            app.WireEndEditFieldLabel.Text = 'Wire End';
            app.WireEndEditField = uieditfield(app.UIFigure, 'text');
            app.WireEndEditField.Value = 'D2';
            app.AddWireButton = uibutton(app.UIFigure, 'push');
            app.AddWireButton.ButtonPushedFcn = createCallbackFcn(app, @AddWireButtonPushed, true);
            app.AddWireButton.Text = 'Add Wire';
            app.RemoveWireButton = uibutton(app.UIFigure, 'push');
            app.RemoveWireButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveWireButtonPushed, true);
            app.RemoveWireButton.Text = 'Remove Wire';
            app.YouWinLabel = uilabel(app.UIFigure);
            app.YouWinLabel.Text = 'You Win';
            app.WiresListBoxLabel = uilabel(app.UIFigure);
            app.WiresListBoxLabel.Text = 'Wires';
            app.WiresListBox = uilistbox(app.UIFigure);
            app.WiresListBox.Items = {};
            app.WiresListBox.Value = {};
        end
    end

    methods (Access = public)

        % Construct app
        function app = prototype_gui

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end